classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CUTSlider                 matlab.ui.control.Slider
        CUTSliderLabel            matlab.ui.control.Label
        biasEditField             matlab.ui.control.NumericEditField
        biasEditFieldLabel        matlab.ui.control.Label
        min_metricEditField       matlab.ui.control.NumericEditField
        min_metricEditFieldLabel  matlab.ui.control.Label
        min_radiusEditField       matlab.ui.control.NumericEditField
        min_radiusEditFieldLabel  matlab.ui.control.Label
        min_VEditField            matlab.ui.control.NumericEditField
        min_VEditFieldLabel       matlab.ui.control.Label
        min_SEditField            matlab.ui.control.NumericEditField
        min_SEditFieldLabel       matlab.ui.control.Label
        FILENAMEEditField         matlab.ui.control.EditField
        FILENAMEEditFieldLabel    matlab.ui.control.Label
        LOADButton                matlab.ui.control.Button
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        OriginalImage
    end

    methods (Access = private)

        function ProcessImage(app)
            if isempty(app.OriginalImage)
                return;
            end

            min_s_val = app.min_SEditField.Value;
            min_v_val = app.min_VEditField.Value;
            min_r_val = app.min_radiusEditField.Value;
            min_met_val = app.min_metricEditField.Value;
            bias_val = app.biasEditField.Value;
            cut_percent = app.CUTSlider.Value;

            im_test = app.OriginalImage;
            [h, w, ~] = size(im_test);

            cut_rows = round(h * (cut_percent / 100));
            if cut_rows > 0 && cut_rows < h
                im_test(end-cut_rows+1:end, :, :) = 0;
            end

            imagesc(app.UIAxes, im_test);
            app.UIAxes.Title.String = 'RGB';
            axis(app.UIAxes, 'off');

            red = 20/255; yellow = 44/255; green = 85/255;

            im_test_h = rgb2hsv(im_test);
            im_test_s = im_test_h;

            imagesc(app.UIAxes3, im_test_h);
            app.UIAxes3.Title.String = 'HSV';
            axis(app.UIAxes3, 'off');

            im_bw = zeros(h, w);
            for i = 1:round(h)
                for j = 1:w
                    if ((im_test_s(i,j,2) > min_s_val) && (im_test_s(i,j,3) > min_v_val))
                        im_bw(i,j) = 1;
                    end
                end
            end
            im_bw = logical(im_bw);

            im_test_s(:,:,1) = abs(mod((im_test_s(:,:,1)+red), 1)-red)+eps;
            im_test_s(:,:,1) = im_test_s(:,:,1) .* im_bw;
            im_test_s(:,:,2) = im_test_s(:,:,2) .* im_bw;
            im_test_s(:,:,3) = im_test_s(:,:,3) .* im_bw;

            imagesc(app.UIAxes2, im_test_s);
            app.UIAxes2.Title.String = 'Segmented';
            axis(app.UIAxes2, 'off');

            imagesc(app.UIAxes4, im_test);
            app.UIAxes4.Title.String = 'RGB+classified';
            axis(app.UIAxes4, 'off');
            hold(app.UIAxes4, 'on');

            for iter = 1:2
                r_min = min_r_val + (iter-1)*10;
                r_max = 3 * r_min;
                try
                    [centers, radii, metric] = imfindcircles(im_bw, [r_min r_max]);
                catch
                    continue;
                end

                centers = round(centers);
                radii = round(radii);
                n = size(radii, 1);

                for p = 1:n
                    if metric(p) < min_met_val
                        continue;
                    end

                    mean_accu = 0;
                    pix = 0;

                    for i = max(1, centers(p,2)-radii(p)) : min(h, centers(p,2)+radii(p))
                        for j = max(1, centers(p,1)-radii(p)) : min(w, centers(p,1)+radii(p))
                            if (im_test_s(i,j,1) ~= 0)
                                mean_accu = mean_accu + im_test_s(i,j,1);
                                pix = pix + 1;
                            end
                        end
                    end

                    if pix > 0
                        m = mean_accu / pix;
                        if (m < red - bias_val)
                            viscircles(app.UIAxes4, centers(p,:), radii(p), 'Color', 'r', 'LineWidth', 3);
                        elseif ((m > red + bias_val) && (m < yellow + red - bias_val))
                            viscircles(app.UIAxes4, centers(p,:), radii(p), 'Color', 'y', 'LineWidth', 3);
                        elseif ((m > yellow + red + bias_val) && (m < 1.5 * green - bias_val))
                            viscircles(app.UIAxes4, centers(p,:), radii(p), 'Color', 'g', 'LineWidth', 3);
                        end
                    end
                end
            end
            hold(app.UIAxes4, 'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'});
            if isequal(filename,0), return; end
            app.OriginalImage = imread(fullfile(pathname, filename));
            app.FILENAMEEditField.Value = filename;
            app.ProcessImage();
        end

        % Value changed function: min_SEditField
        function min_SEditFieldValueChanged(app, event)
            app.ProcessImage();

        end

        % Value changed function: min_VEditField
        function min_VEditFieldValueChanged(app, event)
            app.ProcessImage();
        end

        % Value changed function: min_radiusEditField
        function min_radiusEditFieldValueChanged(app, event)
            app.ProcessImage();
        end

        % Value changed function: min_metricEditField
        function min_metricEditFieldValueChanged(app, event)
            app.ProcessImage();
        end

        % Value changed function: biasEditField
        function biasEditFieldValueChanged(app, event)
            app.ProcessImage();
        end

        % Value changing function: CUTSlider
        function CUTSliderValueChanging(app, event)
            app.ProcessImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RGB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Segmented')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'HSV')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'RGB+classified')
            zlabel(app.UIAxes4, 'Z')
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.FILENAMEEditFieldLabel = uilabel(app.UIFigure);
            app.FILENAMEEditFieldLabel.Text = 'FILE NAME';
            app.FILENAMEEditField = uieditfield(app.UIFigure, 'text');
            app.min_SEditFieldLabel = uilabel(app.UIFigure);
            app.min_SEditFieldLabel.Text = 'min_S';
            app.min_SEditField = uieditfield(app.UIFigure, 'numeric');
            app.min_SEditField.ValueChangedFcn = createCallbackFcn(app, @min_SEditFieldValueChanged, true);
            app.min_SEditField.Value = 0.5;
            app.min_VEditFieldLabel = uilabel(app.UIFigure);
            app.min_VEditFieldLabel.Text = 'min_V';
            app.min_VEditField = uieditfield(app.UIFigure, 'numeric');
            app.min_VEditField.ValueChangedFcn = createCallbackFcn(app, @min_VEditFieldValueChanged, true);
            app.min_VEditField.Value = 0.5;
            app.min_radiusEditFieldLabel = uilabel(app.UIFigure);
            app.min_radiusEditFieldLabel.Text = 'min_radius';
            app.min_radiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.min_radiusEditField.ValueChangedFcn = createCallbackFcn(app, @min_radiusEditFieldValueChanged, true);
            app.min_radiusEditField.Value = 15;
            app.min_metricEditFieldLabel = uilabel(app.UIFigure);
            app.min_metricEditFieldLabel.Text = 'min_metric';
            app.min_metricEditField = uieditfield(app.UIFigure, 'numeric');
            app.min_metricEditField.ValueChangedFcn = createCallbackFcn(app, @min_metricEditFieldValueChanged, true);
            app.min_metricEditField.Value = 0.15;
            app.biasEditFieldLabel = uilabel(app.UIFigure);
            app.biasEditFieldLabel.Text = 'bias';
            app.biasEditField = uieditfield(app.UIFigure, 'numeric');
            app.biasEditField.ValueChangedFcn = createCallbackFcn(app, @biasEditFieldValueChanged, true);
            app.biasEditField.Value = 0.01;
            app.CUTSliderLabel = uilabel(app.UIFigure);
            app.CUTSliderLabel.Text = 'CUT';
            app.CUTSlider = uislider(app.UIFigure);
            app.CUTSlider.ValueChangingFcn = createCallbackFcn(app, @CUTSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end